classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunningLamp                  matlab.ui.control.Lamp
        RunningLampLabel             matlab.ui.control.Label
        ResultsTextArea              matlab.ui.control.TextArea
        ResultsTextAreaLabel         matlab.ui.control.Label
        TrainpercentageSlider        matlab.ui.control.Slider
        TrainpercentageLabel         matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        SelectFeatureExtractionButtonGroup  matlab.ui.container.ButtonGroup
        LESHButton                   matlab.ui.control.RadioButton
        GLCMButton                   matlab.ui.control.RadioButton
        SelectSegmentationAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        RegionGrowingAlgoritmButton  matlab.ui.control.RadioButton
        HammoucheAlgorithmButton     matlab.ui.control.RadioButton
        BackButton                   matlab.ui.control.Button
        RunSVMButton                 matlab.ui.control.Button
        InsertDatasetPanel           matlab.ui.container.Panel
        ChooseCSVButton              matlab.ui.control.Button
        ChooseFolderButton           matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        WelcomeAdminLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        folder % Description
        csvFile
        training_percentage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)

            app.folder = uigetdir('C:\');
            % app.folder = uigetdir('C:\Users\New Hp\Documents\MATLAB\fit3162\Code');
            app.Label_2.Text=app.folder;
            app.Label_2.Text
        end

        % Button pushed function: ChooseCSVButton
        function ChooseCSVButtonPushed(app, event)
%             app.folder = uigetdir('G:\');
            [file,path] = uigetfile({'*.xls';'*.xlsx';'*.csv';});
            file = fullfile(path, file);
            % app.folder = uigetdir('C:\Users\New Hp\Documents\MATLAB\fit3162\Code');
            if isequal(file, 0)
               disp('User aborted file choosing.');
               return;  % Assuming this is a function
            end
            app.csvFile=readtable(file);
        end

        % Value changed function: TrainpercentageSlider
        function TrainpercentageSliderValueChanged(app, event)
            value = app.TrainpercentageSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            if minIdx == 1
                app.training_percentage = 0.50;
                disp(50);
            elseif minIdx ==2
                app.training_percentage = 0.55;
                disp(55);
            elseif minIdx ==3
               app.training_percentage = 0.60;
               disp(60);
            elseif minIdx ==4
               app.training_percentage = 0.65;
               disp(65);
            elseif minIdx ==5
               app.training_percentage = 0.70;
               disp(70);
            elseif minIdx ==6
               app.training_percentage = 0.75;
               disp(75);
            elseif minIdx ==7
               app.training_percentage = 0.80;
               disp(80);
            elseif minIdx ==8
               app.training_percentage = 0.85;
               disp(85);
            elseif minIdx ==9
               app.training_percentage = 0.90;
               disp(90);
            elseif minIdx ==10
               app.training_percentage = 0.95;
               disp(95);
            elseif minIdx ==11
               app.training_percentage = 1;
               disp(100);
            end
            
            % Override the selected value if you plan on using it within this function

            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app1
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: RunSVMButton
        function RunSVMButtonPushed(app, event)
%             x=6;
%             y=2;
%             val=0;
            seg=app.SelectSegmentationAlgorithmButtonGroup.SelectedObject.Text;
            fea=app.SelectFeatureExtractionButtonGroup.SelectedObject.Text;
            app.RunningLamp.Color='yellow';
            drawnow
            % Hammouche
            if seg=="Hammouche Algorithm"
                if fea=="GLCM"
%                     val=add(6,2);
                    outputTable = hammoucheGLCM(app.folder,app.csvFile);
%                     con_matric=SVM_GLCM("results_extra_hammouche_glcm_3class.xlsx",training_percentage)
                    [con_matric,noClasses,accuracy]=SVM_GLCM(outputTable,app.training_percentage);
%                     disp(con_matric)
                else
                    outputTable=hammoucheLESH(app.folder,app.csvFile);
%                     con_matric=SVM_LESH("results_extra_hammouche_lesh_3class.xlsx",training_percentage);
                    [con_matric,noClasses,accuracy]=SVM_LESH(outputTable,app.training_percentage);
                    disp(con_matric)
%                     val=minusnum(6,2);
                end
                
            % Region Growing
            else
                if fea=="LESH"
                    outputTable = regionGrowingLESH(app.folder,app.csvFile);
%                     con_matric=SVM_LESH("results_extra_rgrowing_lesh_3class.xlsx",training_percentage)
                    [con_matric,noClasses,accuracy]=SVM_LESH(outputTable,app.training_percentage);
%                     disp(con_matric)
%                     val=multiplynum(6,2);
                    
                else
                    outputTable= regionGrowingGLCM(app.folder,app.csvFile);
%                     con_matric=SVM_GLCM("results_extra_rgrowing_glcm_3class.xlsx",training_percentage)
                    [con_matric,noClasses,accuracy]=SVM_GLCM(outputTable,app.training_percentage);
%                     disp(con_matric)
%                     val=divnum(6,2);
                end
            end
            app.RunningLamp.Color='green';
            drawnow
            app.ResultsTextArea.Visible = 'on';
            app.ResultsTextAreaLabel.Visible= 'on';
            app.ResultsTextArea.Value{1}=['There are ' num2str(noClasses) ' classes detected'];
            app.ResultsTextArea.Value{2} =['Accuracy Of Implementation:' num2str(accuracy)];
            app.ResultsTextArea.Value{3} =('Confusion Matrix Of The Implementation');
            app.ResultsTextArea.Value{4} =mat2str(con_matric);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomeAdminLabel = uilabel(app.UIFigure);
            app.WelcomeAdminLabel.Text = 'Welcome Admin';
            app.InsertDatasetPanel = uipanel(app.UIFigure);
            app.InsertDatasetPanel.Title = 'Insert Dataset';
            app.Label = uilabel(app.InsertDatasetPanel);
            app.Label.Text = '';
            app.ChooseFolderButton = uibutton(app.InsertDatasetPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.ChooseCSVButton = uibutton(app.InsertDatasetPanel, 'push');
            app.ChooseCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVButtonPushed, true);
            app.ChooseCSVButton.Text = 'Choose CSV';
            app.RunSVMButton = uibutton(app.UIFigure, 'push');
            app.RunSVMButton.ButtonPushedFcn = createCallbackFcn(app, @RunSVMButtonPushed, true);
            app.RunSVMButton.Text = 'Run SVM';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SelectSegmentationAlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectSegmentationAlgorithmButtonGroup.Title = 'Select Segmentation Algorithm';
            app.HammoucheAlgorithmButton = uiradiobutton(app.SelectSegmentationAlgorithmButtonGroup);
            app.HammoucheAlgorithmButton.Text = 'Hammouche Algorithm';
            app.HammoucheAlgorithmButton.Value = true;
            app.RegionGrowingAlgoritmButton = uiradiobutton(app.SelectSegmentationAlgorithmButtonGroup);
            app.RegionGrowingAlgoritmButton.Text = 'Region Growing Algoritm';
            app.SelectFeatureExtractionButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectFeatureExtractionButtonGroup.Title = 'Select Feature Extraction';
            app.GLCMButton = uiradiobutton(app.SelectFeatureExtractionButtonGroup);
            app.GLCMButton.Text = 'GLCM';
            app.GLCMButton.Value = true;
            app.LESHButton = uiradiobutton(app.SelectFeatureExtractionButtonGroup);
            app.LESHButton.Text = 'LESH';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {''; ''};
            app.TrainpercentageLabel = uilabel(app.UIFigure);
            app.TrainpercentageLabel.Text = 'Train percentage (%)';
            app.TrainpercentageSlider = uislider(app.UIFigure);
            app.TrainpercentageSlider.Limits = [0 10];
            app.TrainpercentageSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.TrainpercentageSlider.MajorTickLabels = {'50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100'};
            app.TrainpercentageSlider.ValueChangedFcn = createCallbackFcn(app, @TrainpercentageSliderValueChanged, true);
            app.TrainpercentageSlider.MinorTicks = [];
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Visible = 'off';
            app.ResultsTextAreaLabel.Text = 'Results:';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.Editable = 'off';
            app.ResultsTextArea.Visible = 'off';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end