classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PredictionResultsLabel  matlab.ui.control.Label
        ResultsTextArea         matlab.ui.control.TextArea
        BackButton              matlab.ui.control.Button
        PredictButton           matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        SelectImagePanel        matlab.ui.container.Panel
        SelectImageButton       matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        WelcomeUserLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        imageChosen="" % Description      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app1
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            %fuction for user to choose the image
            [baseFileName, folder] = uigetfile({'*.jpg';'*.png'});  % only allow the selection of image files
            fullFileName = fullfile(folder, baseFileName);
            try
                theImage = imread(fullFileName);
            catch ME
                figure(app.UIFigure); % to pop the UI back after choosing folder
                message = {'Wrong File Selected!','Please select a an image'};
                uialert(app.UIFigure,message,'Incorrect Input','Icon','warning');
                app.imageChosen="";
                return
            end
            app.imageChosen=theImage;
            app.Image.ImageSource=theImage;
            figure(app.UIFigure); % to pop the UI back after choosing folder
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % segment it
            % extract feature extraction
            % feed into SVM
            if class(app.imageChosen) ~= "uint8"
                message = {'Image Not Selected!','Please select an image'};
                uialert(app.UIFigure,message,'Incorrect Input','Icon','error');
                return
            end
            I=pre_process(app.imageChosen); %pre-process the image
            %apply region Growing
            I = im2gray(I);
            I = double(I);
            Seeds = [180];
            SI = I == Seeds;
            T = 30;
            TI = false(size(I));
            for i = 1:length(Seeds)
                seed_val = Seeds(i);
                S = abs(I-seed_val) <= T;
                TI = TI | S;
            end
            %applying GLCM
            IM = imreconstruct(SI,TI);
            [GLCM, ~] = graycomatrix(IM,'Offset',[1 1],'NumLevels',2,'GrayLimits',[],'Symmetric', true);
            stats = graycoprops(GLCM,'all');
            cell = struct2cell(stats);
            flipCell = cell.' ;
            Table = cell2table(flipCell);
            Table.Properties.VariableNames = {'Contrast' 'Correlation' 'Energy' 'Homogeneity'};
            %the creation of traning model
            %load the saved traning model
            load UserSVMModel GLCMModel
            %predict the image chosen by user
            TestOutputs = predict(GLCMModel,Table);
            result=string(TestOutputs);
            %ouput into the text Area
            app.ResultsTextArea.Visible = 'on';
            app.ResultsTextArea.Value{1}=char("Segmentation Algorithm used is the Region Growing Algorithm");
            app.ResultsTextArea.Value{2}=char("Feature Extraction Algorithm used is GLCM");
            if result=="N"
                app.ResultsTextArea.Value{3}=char(("The Prediction Result is NON-CANCEROUS"));
            elseif result =="B"
                app.ResultsTextArea.Value{3}=char(("The Prediction Result is CANCEROUS "));
                app.ResultsTextArea.Value{4}=char(("The Type of cancer is BENIGN "));
            elseif result == "M"
                app.ResultsTextArea.Value{3}=char(("The Prediction Result is CANCEROUS "));
                app.ResultsTextArea.Value{4}=char(("The Type of cancer is MALIGNANT"));
            elseif result == "C"
                app.ResultsTextArea.Value{3}=char(("The Prediction Result is CANCEROUS"));
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomeUserLabel = uilabel(app.UIFigure);
            app.WelcomeUserLabel.Text = 'Welcome User';
            app.SelectImagePanel = uipanel(app.UIFigure);
            app.SelectImagePanel.Title = 'Select Image';
            app.Label = uilabel(app.SelectImagePanel);
            app.Label.Text = '';
            app.SelectImageButton = uibutton(app.SelectImagePanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.Image = uiimage(app.UIFigure);
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.Editable = 'off';
            app.PredictionResultsLabel = uilabel(app.UIFigure);
            app.PredictionResultsLabel.Text = 'Prediction Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end